classdef espectro_transformada_fourier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        grafica_espectro          matlab.ui.control.UIAxes
        AmplitudEditFieldLabel    matlab.ui.control.Label
        A                         matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel  matlab.ui.control.Label
        f                         matlab.ui.control.NumericEditField
        ComponentesdeperiodoEditFieldLabel  matlab.ui.control.Label
        cp                        matlab.ui.control.NumericEditField
        grafica_cuadrada          matlab.ui.control.UIAxes
        ejecutarButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ejecutarButton
        function ejecutarButtonPushed(app, event)
            t = linspace(0,app.cp.Value/app.f.Value,500);
            cuadrada = (1/1)*sin(2*pi*1*app.f.Value*t) + (1/3)*sin(2*pi*3*app.f.Value*t) + (1/5)*sin(2*pi*5*app.f.Value*t) + (1/7)*sin(2*pi*7*app.f.Value*t) + (1/9)*sin(2*pi*9*app.f.Value*t);
            espectro = abs (fftshift(fft(cuadrada)));
            plot(app.grafica_cuadrada,t,cuadrada)
            plot(app.grafica_espectro,t,espectro)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.grafica_espectro = uiaxes(app.UIFigure);
            title(app.grafica_espectro, 'Title')
            xlabel(app.grafica_espectro, 'X')
            ylabel(app.grafica_espectro, 'Y')
            app.AmplitudEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.A = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciaEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.f = uieditfield(app.UIFigure, 'numeric');
            app.ComponentesdeperiodoEditFieldLabel = uilabel(app.UIFigure);
            app.ComponentesdeperiodoEditFieldLabel.Text = {'Componentes'; 'de periodo'};
            app.cp = uieditfield(app.UIFigure, 'numeric');
            app.grafica_cuadrada = uiaxes(app.UIFigure);
            title(app.grafica_cuadrada, 'Title')
            xlabel(app.grafica_cuadrada, 'X')
            ylabel(app.grafica_cuadrada, 'Y')
            app.ejecutarButton = uibutton(app.UIFigure, 'push');
            app.ejecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ejecutarButtonPushed, true);
            app.ejecutarButton.Text = 'ejecutar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = espectro_transformada_fourier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end