classdef modulacion_digital < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        grafica                      matlab.ui.control.UIAxes
        ejecutarButton               matlab.ui.control.Button
        SealBinariaCheckBox          matlab.ui.control.CheckBox
        CarrierCheckBox              matlab.ui.control.CheckBox
        ASKCheckBox                  matlab.ui.control.CheckBox
        DatosBinariosEditFieldLabel  matlab.ui.control.Label
        datos                        matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ejecutarButton
        function ejecutarButtonPushed(app, event)
            valueC = app.CarrierCheckBox.Value;
            valueSB = app.SealBinariaCheckBox.Value;
            valueAsk = app.ASKCheckBox.Value;
            
            regla_bit_alto = 1;


            %Carrier
            Ac= 10;
            fc=1000e3;
            thetac =0;
            cpc=15;
            
            
            %ask
            
            regla_ask_tr_carrier=1;
            tb=100; %duracion del bit
            uno = ones(1,tb); %matriz de unos
            cero = zeros(1,tb); %matriz de ceros
            frame=[];
            frameInv=[];    
            list=[];
            f='0';
            for n=1:length(app.datos.Value)
                if (app.datos.Value(n)=='1')
                    frame = [ frame uno ]; %vector que se autoacumula
                    frameInv = [frameInv cero] ;
                else 
                    frame = [ frame cero ];
                    frameInv = [frameInv uno];
                end
                if (app.datos.Value(n)~= f)
                    f=app.datos.Value(n);
                    list = [list length(frame)];
                end
            end
            if(regla_bit_alto==1)
                cadenaBits=5*frame; %0 a 5 voltios
            else
                cadenaBits=5*frameInv;
            end
            
            tc = linspace(0, 2*length(app.datos.Value)/fc,length(cadenaBits));
            carrier = Ac* sin(2*pi*fc*tc+thetac);
            if(valueC==1)
                plot(app.grafica,tc,carrier)
                
            end
            if(valueSB==1)
                plot(app.grafica,cadenaBits)
                xticks(list-100)
                axis([0 length(cadenaBits) -1 6])
            end
            if(valueAsk==1)
                if(regla_ask_tr_carrier == 1)
                    ask = frame.*carrier;
                else
                    ask = frameInv.*carrier;
                end
                plot(app.grafica,ask) 
                xticks(list-100)
                axis([0 length(cadenaBits) -10 10])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            app.ejecutarButton = uibutton(app.UIFigure, 'push');
            app.ejecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ejecutarButtonPushed, true);
            app.ejecutarButton.Text = 'ejecutar';
            app.SealBinariaCheckBox = uicheckbox(app.UIFigure);
            app.SealBinariaCheckBox.Text = 'Señal Binaria';
            app.CarrierCheckBox = uicheckbox(app.UIFigure);
            app.CarrierCheckBox.Text = 'Carrier';
            app.ASKCheckBox = uicheckbox(app.UIFigure);
            app.ASKCheckBox.Text = 'ASK';
            app.DatosBinariosEditFieldLabel = uilabel(app.UIFigure);
            app.DatosBinariosEditFieldLabel.Text = 'Datos Binarios';
            app.datos = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modulacion_digital

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end