classdef modulaciones_analogicas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        grafica                 matlab.ui.control.UIAxes
        carrierCheckBox         matlab.ui.control.CheckBox
        xtCheckBox              matlab.ui.control.CheckBox
        AMCheckBox              matlab.ui.control.CheckBox
        FMCheckBox              matlab.ui.control.CheckBox
        PMCheckBox              matlab.ui.control.CheckBox
        ejecutarButton          matlab.ui.control.Button
        IndiceAMEditFieldLabel  matlab.ui.control.Label
        m                       matlab.ui.control.NumericEditField
        IndiceFMEditFieldLabel  matlab.ui.control.Label
        nfm                     matlab.ui.control.NumericEditField
        IndicePMEditFieldLabel  matlab.ui.control.Label
        npm                     matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ejecutarButton
        function ejecutarButtonPushed(app, event)
            valueC = app.carrierCheckBox.Value;
            valuext = app.xtCheckBox.Value;
            valueAM = app.AMCheckBox.Value;
            valueFM = app.FMCheckBox.Value;
            valuePM = app.PMCheckBox.Value;
            
            %carrier
            Ac=10;
            fc=1000e3;
            thetac=0;
            cpc=15;
            
            %x(t)
            Ax=2;
            fx=3400;
            thetax=0;
            cpx=2;
            %carrier
            tc=linspace(0,cpc/fc,500);
            carrier = Ac*sin(2*pi*fc*tc+thetac);
            if(valueC==1)
                plot(app.grafica,tc,carrier)
            end
            %xt
            tx=linspace(0,cpx/fx,500);
            xt=Ax*sin(2*pi*fx*tx+thetax);
            if(valuext==1)
                
                plot(app.grafica,tx,xt)
            end
            %am
            if(valueAM==1)
                xam = (1+app.m.Value*xt).*carrier;
                plot(app.grafica,tc,xam)
            end
            if(valueFM==1)
                xfm=Ac*sin(2*pi*fc*tc+app.nfm.Value*xt);
                plot(app.grafica,tc,xfm)
                
            end
            if(valuePM==1)
                xt_diff=Ax*sin(2*pi*fx*tx+(thetax+pi/2));
                xpm=Ac*sin(2*pi*fc*tc+app.npm.Value*xt_diff);
                plot(app.grafica,tc,xpm)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            app.carrierCheckBox = uicheckbox(app.UIFigure);
            app.carrierCheckBox.Text = 'carrier';
            app.xtCheckBox = uicheckbox(app.UIFigure);
            app.xtCheckBox.Text = 'x(t)';
            app.AMCheckBox = uicheckbox(app.UIFigure);
            app.AMCheckBox.Text = 'AM';
            app.FMCheckBox = uicheckbox(app.UIFigure);
            app.FMCheckBox.Text = 'FM';
            app.PMCheckBox = uicheckbox(app.UIFigure);
            app.PMCheckBox.Text = 'PM';
            app.ejecutarButton = uibutton(app.UIFigure, 'push');
            app.ejecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ejecutarButtonPushed, true);
            app.ejecutarButton.Text = 'ejecutar';
            app.IndiceAMEditFieldLabel = uilabel(app.UIFigure);
            app.IndiceAMEditFieldLabel.Text = 'Indice AM';
            app.m = uieditfield(app.UIFigure, 'numeric');
            app.IndiceFMEditFieldLabel = uilabel(app.UIFigure);
            app.IndiceFMEditFieldLabel.Text = 'Indice FM';
            app.nfm = uieditfield(app.UIFigure, 'numeric');
            app.IndicePMEditFieldLabel = uilabel(app.UIFigure);
            app.IndicePMEditFieldLabel.Text = 'Indice PM';
            app.npm = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modulaciones_analogicas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end