classdef series_de_fourier_interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        grafica                   matlab.ui.control.UIAxes
        ejecutarButton            matlab.ui.control.Button
        frecuenciaEditFieldLabel  matlab.ui.control.Label
        f                         matlab.ui.control.NumericEditField
        amplitudEditFieldLabel    matlab.ui.control.Label
        A                         matlab.ui.control.NumericEditField
        componentedefrecuenciaEditFieldLabel  matlab.ui.control.Label
        can_frecuencias           matlab.ui.control.NumericEditField
        ComponentedeperiodoEditFieldLabel  matlab.ui.control.Label
        can_periodos              matlab.ui.control.NumericEditField
        TriangularCheckBox        matlab.ui.control.CheckBox
        DientesdeSierraCheckBox   matlab.ui.control.CheckBox
        Sinusoidal0CheckBox       matlab.ui.control.CheckBox
        Sinusoidal90CheckBox      matlab.ui.control.CheckBox
        Sinusoidal180CheckBox     matlab.ui.control.CheckBox
        Sinusoidal270CheckBox     matlab.ui.control.CheckBox
        CuadradaCheckBox          matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CuadradaCheckBox, ejecutarButton
        function ejecutarButtonPushed(app, event)
            valueC = app.CuadradaCheckBox.Value;
            valueT = app.TriangularCheckBox.Value;
            valueD = app.DientesdeSierraCheckBox.Value;
            valueS0 = app.Sinusoidal0CheckBox.Value;
            valueS90 = app.Sinusoidal90CheckBox.Value;
            valueS180 = app.Sinusoidal180CheckBox.Value;
            valueS270 = app.Sinusoidal270CheckBox.Value;
            w=2*pi*app.f.Value;
            t = linspace(0,app.can_periodos.Value/app.f.Value,500);
            cuadrada=0;
            %cuadrada
            if (valueC==1)
                for N=1:app.can_frecuencias.Value+app.can_frecuencias.Value-1
                    if(mod(N,2)~=0)
                        cuadrada=cuadrada+(1/N)*sin(2*pi*N*app.f.Value*t);
                    end
    
                end
                plot(app.grafica,t,cuadrada)
            end
            %triangular
            if (valueT==1)
                tri=0;
                for N=1:2:(app.can_frecuencias.Value*2-1)
   
                    tri = tri + ((pi/2)-(4/pi)*(1/N^2)*(cos(w*t*N)));
                end
                plot(app.grafica,t,tri)
            end
            %diente de sierra
            if (valueD==1)
                diente=0;
                for N=1:(app.can_frecuencias.Value)
   
                    diente = diente + (((2*app.A.Value)/pi)*((-1)^(N+1))*((1/N)*sin(t*w*N)));
    
                end
                plot(app.grafica,t,diente)
            end
            
            %sinu0
            if(valueS0==1)
                sinu0 = app.A.Value*sin(2*pi*app.f.Value*t);
                plot(app.grafica,t,sinu0)
            end
            %sinu90
            if(valueS90==1)
                sinu90 = app.A.Value*sin(2*pi*app.f.Value*t+pi/2);
                plot(app.grafica,t,sinu90)
            end
            %sinu180
            if(valueS180==1)
                sinu180 = app.A.Value*sin(2*pi*app.f.Value*t+pi);
                plot(app.grafica,t,sinu180)
            end
            %sinu0
            if(valueS270==1)
                sinu270 = app.A.Value*sin(2*pi*app.f.Value*t+3*pi/2);
                plot(app.grafica,t,sinu270)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            app.ejecutarButton = uibutton(app.UIFigure, 'push');
            app.ejecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ejecutarButtonPushed, true);
            app.ejecutarButton.Text = 'ejecutar';
            app.frecuenciaEditFieldLabel = uilabel(app.UIFigure);
            app.frecuenciaEditFieldLabel.Text = 'frecuencia';
            app.f = uieditfield(app.UIFigure, 'numeric');
            app.amplitudEditFieldLabel = uilabel(app.UIFigure);
            app.amplitudEditFieldLabel.Text = 'amplitud';
            app.A = uieditfield(app.UIFigure, 'numeric');
            app.componentedefrecuenciaEditFieldLabel = uilabel(app.UIFigure);
            app.componentedefrecuenciaEditFieldLabel.Text = {'componente'; 'de frecuencia'};
            app.can_frecuencias = uieditfield(app.UIFigure, 'numeric');
            app.ComponentedeperiodoEditFieldLabel = uilabel(app.UIFigure);
            app.ComponentedeperiodoEditFieldLabel.Text = {'Componente '; 'de periodo'};
            app.can_periodos = uieditfield(app.UIFigure, 'numeric');
            app.TriangularCheckBox = uicheckbox(app.UIFigure);
            app.TriangularCheckBox.Text = 'Triangular';
            app.DientesdeSierraCheckBox = uicheckbox(app.UIFigure);
            app.DientesdeSierraCheckBox.Text = 'Dientes de Sierra';
            app.Sinusoidal0CheckBox = uicheckbox(app.UIFigure);
            app.Sinusoidal0CheckBox.Text = 'Sinusoidal 0';
            app.Sinusoidal90CheckBox = uicheckbox(app.UIFigure);
            app.Sinusoidal90CheckBox.Text = 'Sinusoidal 90';
            app.Sinusoidal180CheckBox = uicheckbox(app.UIFigure);
            app.Sinusoidal180CheckBox.Text = 'Sinusoidal 180';
            app.Sinusoidal270CheckBox = uicheckbox(app.UIFigure);
            app.Sinusoidal270CheckBox.Text = 'Sinusoidal 270';
            app.CuadradaCheckBox = uicheckbox(app.UIFigure);
            app.CuadradaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ejecutarButtonPushed, true);
            app.CuadradaCheckBox.Text = 'Cuadrada';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = series_de_fourier_interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end